classdef MatGPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatGPTUIFigure                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ChatGridLayout                  matlab.ui.container.GridLayout
        PaperClip                       matlab.ui.control.Image
        SuggestfollowupquestionsCheckBox  matlab.ui.control.CheckBox
        TestGeneratedMATLABCodeCheckBox  matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        ChatTab                         matlab.ui.container.Tab
        ChatHTML                        matlab.ui.control.HTML
        UsageTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UsagedataisnotavailableinstreamingmodeLabel  matlab.ui.control.Label
        TocalculateAPIfeespleaseconsultLabel  matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        TotaltokensusedEditField        matlab.ui.control.NumericEditField
        TotaltokensusedEditFieldLabel   matlab.ui.control.Label
        PrompttokensusedEditField       matlab.ui.control.NumericEditField
        PrompttokensusedEditFieldLabel  matlab.ui.control.Label
        CompletiontokensusedEditField   matlab.ui.control.NumericEditField
        CompletiontokensusedEditFieldLabel  matlab.ui.control.Label
        PromptTextArea                  matlab.ui.control.TextArea
        ChatTree                        matlab.ui.container.Tree
        Logo                            matlab.ui.control.Image
        SendButton                      matlab.ui.control.Button
        MatGPTpoweredbyOpenAIChatGPTAPILabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        SettingsGridLayout              matlab.ui.container.GridLayout
        UsagedataisnotavailableinstreamingmodeLabel_2  matlab.ui.control.Label
        StreamResponseCheckBox          matlab.ui.control.CheckBox
        secsLabel                       matlab.ui.control.Label
        TimeoutEditField                matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel           matlab.ui.control.Label
        KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel  matlab.ui.control.Label
        SaveKeyButton                   matlab.ui.control.Button
        YourOpenAIAPIKeyEditField       matlab.ui.control.EditField
        YourOpenAIAPIKeyEditFieldLabel  matlab.ui.control.Label
        APIKeyNotFoundLamp              matlab.ui.control.Lamp
        APIKeyNotFoundLampLabel         matlab.ui.control.Label
        PresetsListBox                  matlab.ui.control.ListBox
        PresetsListBoxLabel             matlab.ui.control.Label
        SystemPromptTextArea            matlab.ui.control.TextArea
        SystemPromptTextAreaLabel       matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        MaxTokensEditField              matlab.ui.control.NumericEditField
        MaxTokensEditFieldLabel         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelAttributesLabel            matlab.ui.control.Label
        SetOpenAIAPIKeyLabel            matlab.ui.control.Label
        OrcustomizeparametersyourselfLabel  matlab.ui.control.Label
        GetyourAPIkeyfromLabel          matlab.ui.control.Label
        ChooseapresetLabel              matlab.ui.control.Label
        StartNewChatButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        moreprecise1balanced2morecreativeLabel  matlab.ui.control.Label
        MaximumnumberoftokenstoincludeinresponseLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        AdvancedTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        Sequence4EditField              matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        Sequence3EditField              matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        Sequence2EditField              matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        Sequence1EditField              matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        StopSequencesLabel              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        SavetofileMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % openAIChat object
        Bot;
        % OpenAIMessages object
        Messages;
        % supported models
        Models = [ ...
            struct('name','gpt-3.5-turbo','attributes',struct('contextwindow',4096,'cutoff','Sep 2021'),'legacy',false), ...
            struct('name','gpt-3.5-turbo-1106','attributes',struct('contextwindow',16385,'cutoff','Sep 2021'),'legacy',false), ...
            struct('name','gpt-3.5-turbo-16k','attributes',struct('contextwindow',16385,'cutoff','Sep 2021'),'legacy',false), ...
            struct('name','gpt-4','attributes',struct('contextwindow',8192,'cutoff','Sep 2021'),'legacy',false), ...
            struct('name','gpt-4-0613','attributes',struct('contextwindow',8192,'cutoff','Sep 2021'),'legacy',false), ...
            struct('name','gpt-4-1106-preview','attributes',struct('contextwindow',128000,'cutoff','Apr 2023'),'legacy',false), ...
            struct('name','gpt-4-vision-preview','attributes',struct('contextwindow',128000,'cutoff','Apr 2023'),'legacy',false), ...
            struct('name','gpt-4-turbo-preview','attributes',struct('contextwindow',128000,'cutoff','Apr 2023'),'legacy',false) ...
            ];

        % presets
        Presets;
        % path of the app file
        Path;
        % holds most recent test report
        TestReport;
        % holds most recent error messages
        ErrorMessages;
        % max context window size (tokens)
        ContextWindow = 4096;
        % image path
        Image;
    end
    
    methods (Access = private)
                
        function resetChatHTML(app)
            % RESETCHATHTML - Reset the chat HTML source (happens whenever
            % switching to a previous chat or starting a new chat)
            app.ChatHTML.HTMLSource = "";
            app.ChatHTML.Data = [];
            drawnow
            app.ChatHTML.HTMLSource = fullfile(app.Path,"contents","index.html");
            drawnow
        end
        
        function addChatLog(app,role,content)
            % ADDCHATLOG - Add a row to the Chat History table by changing
            % the "Data" property. This triggers an event listener to update
            % the HTML.

            % Use most recent chat log if not specified
            if nargin<2
                data = app.ChatTree.SelectedNodes.NodeData;
                % extract role and content from Messages
                role = data.messages.Messages{end}.role;
                content = data.messages.Messages{end}.content;
            end
            if ismember(role,["assistant","MATLAB"])
                % convert markdown tables and code blocks to HTML
                htmlContent = TextHelper.replaceTableMarkdown(content);
                % convert ``` markdown from responses to our code style
                htmlContent = TextHelper.replaceCodeMarkdown(htmlContent);
                % Update the uihtml Data property to trigger the JavaScript 
                % listener that will add a row to the HTML table
                if app.StreamResponseCheckBox.Value && role == "assistant"
                    app.ChatHTML.Data = {role,htmlContent,"current"};
                else
                    app.ChatHTML.Data = {role,htmlContent,"new"};
                end
            else
                app.ChatHTML.Data = {role,content,"new"};
            end
        end

        function streamTokens(app,token)
        %STREAMTOKENS prints the streamed tokens in a new row in the table
            if strlength(token) == 0
                % if the first token is 0 length, add a new row
                tokens = string(token);
                app.ChatHTML.Data = {"assistant",tokens,"new"};
            else
                % otherwise append the new token to the previous tokens
                % if the new token contains a line break, replace 
                % it with <br>
                if contains(token,newline)
                    token = replace(token,newline,"<br>");
                end
                tokens = app.ChatHTML.Data{2} + string(token);
                % update the existing row. 
                app.ChatHTML.Data = {"assistant",tokens,"current"};
            end
            drawnow
        end
        
        function content = getContentFromURL(app, prompt)
        %GETCONTENTFROMURL retrieves content from the URL
            % check if the prompt contains any url
            urlPat = ("http://" | "https://") + ...
                asManyOfPattern(alphanumericsPattern(1) | ...
                    characterListPattern("./_-:;%&?#"), 1);
            url = extract(prompt,urlPat);
            % if the url was given, get confirmation to access it
            if strlength(url)
                % check how many URLs were detected
                if numel(url) > 1
                    msg = "Multiple URLs were detected. Please use only one URL";
                    uialert(app.MatGPTUIFigure,msg,"Error: Too many URLs")
                    content = "";
                    return
                end
                % ask for confirmation
                msg = "Are you sure you want to load the content of " + ...
                    "this page in MATLAB? If the page is over the " + ...
                    "token limit, the content will be truncated.";
                ttl = "URL Detected";
                selection = uiconfirm(app.MatGPTUIFigure, msg + newline + newline + url, ...
                    ttl, Icon="warning");
                % if confirmed
                switch selection
                    % if user confirmed
                    case 'OK'
                        % read the page
                        try
                            page = webread(url);
                        catch ME
                            uialert(app.MatGPTUIFigure,ME.message,"Error");
                            content = 0;
                            return
                        end
                        % if the content is text
                        if ischar(page)
                        % extract HTML content
                            webContent = TextHelper.extractContentfromHTML(page);
                            % Add meta data
                            webContent = "The content of URL " + url + ": " + newline + webContent;
                            % make sure it fits the context window
                            content = fitContextWindow(app,webContent);
                        elseif isa(page,'uint8')
                            content = url;
                        end
                    otherwise
                        content = "";
                end
            else
                content = "";
            end
            
        end
    
        function fitted = fitContextWindow(app,content)
            % FITCONTEXTWINDOW - makes sure content fits the context window
            % get the model name
            model = app.Bot.ModelName;
            % update the context window value
            app.ContextWindow = app.Models(arrayfun(@(x) string(x.name), app.Models) == model).attributes.contextwindow;
            % subtract the max tokens to derive the available window
            max_length = app.ContextWindow - app.MaxTokensEditField.Value;
            % 1 token ~= 4 chars in English
            max_length = max_length * 2; % be conservative
            % if the content fits within the context window
            if strlength(content) < max_length
                % do nothing
                fitted = content;
            else
                % truncate
                truncated = char(content);
                truncated = string(truncated(1:max_length));
                % drop the last word
                splitted = split(truncated,whitespacePattern);
                fitted = erase(truncated,splitted(end) + textBoundary("end"));
            end
        end
    
        
        function messages = addFunctionMessage(app,content)
            % add assistant message with tool calls
            id = "call_" + string(datetime('now',Format="yyyyMMddHHmmss"));
            functionName = "MATLAB";
            args = "{""arg1"": 1 }";
            funCall = struct("name", functionName, "arguments", args);
            toolCall = struct("id", id, "type", "function", "function", funCall);
            toolCallPrompt = struct("role", "assistant", "content", "", "tool_calls", toolCall);
            app.Messages = addResponseMessage(app.Messages, toolCallPrompt);
            % add content as the function result
            messages = addToolMessage(app.Messages,id,functionName,content);
        end
        
        function messages = removeTestReports(app)
            % remove test reports before sending user prompt    
            messages = app.Messages;
            if app.Bot.ModelName ~= "gpt-4-vision-preview"
                % extract content from messages
                contents = cellfun(@(x) x.content, messages.Messages);
                isTestReport = startsWith(contents,'<div class="test-report">');
                if any(isTestReport)
                    idx1 = find(isTestReport);
                    for ii = numel(idx1):-1:1
                        % get the tool call id
                        id1 = messages.Messages{idx1(ii)}.tool_call_id;
                        messages = removeMessage(messages,idx1(ii));
                        % must also remove the matching tool call
                        isToolCall = strlength(contents) == 0;
                        idx2 = find(isToolCall);
                        for jj = numel(idx2):-1:1
                            % get the tool call id
                            id2 = messages.Messages{idx2(jj)}.tool_calls{1}.id;
                            % if tool call ids match
                            if id1 == id2
                                messages = removeMessage(messages,idx2(jj));
                            end
                        end
                    end
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                try
                    %% load external files
                    % get the path of the app file
                    [app.Path,~,~] = fileparts(mfilename("fullpath"));
                    % add path to helper classes and functions
                    addpath(fullfile(app.Path,"helpers"));
                    addpath(fullfile(app.Path,"helpers/llms-with-matlab"));
                    % load presets from csv file
                    app.Presets = readtable(fullfile(app.Path,"contents","presets.csv"), ...
                        TextType="string",Delimiter=",");
                    % load chat history data
                    chatHistoryFile = fullfile(app.Path,"contents","ChatData.mat");
                    % Initialize the HTML files
                    resetChatHTML(app)
                    % display the startup message
                    role = "MatGPT";
                    content = "This app provides access to the ChatGPT API " + ...
                        "from OpenAI. To use this app, you need to obtain " + ...
                        "your own API key via <a href='https://platform.openai.com/account/api-keys' target='_blank'>" + ...
                        "https://platform.openai.com/account/api-keys</a>. " + ...
                        "By obtaining and using the API key in this app, " + ...
                        "you agree to OpenAI's <a href='https://openai.com/policies' target='_blank'>" + ...
                        "terms and policies</a>.";
                    addChatLog(app,role,content);
                    drawnow
                    pause(1/20)
                    content = "<p style='font-size: 1.1em; font-weight: bold'>To get started, click ""+ New Chat"" or the name of a previous chat.</p>";
                    addChatLog(app,role,content);
                    drawnow
                    pause(1/20)
                    content = "<strong>What's New</strong>: " + ... 
                        "<ul><li>Runs on the <a href='https://github.com/matlab-deep-learning/llms-with-matlab' target='_blank'>LLMs with MATLAB</a> framework, which requires MATLAB R2023a or later</li>" + ...
                        "<li>Detects a URL included in a prompt, and retrieve its content into the chat.</<li>" + ...
                        "<li>Lets you import a .m, .mlx, .csv, or .txt file into the chat</li>" + ...
                        "<li>Supports GPT-4 Turbo with Vision with .jpg, .png or .gif images</li></ul>" + ...
                        "<strong>Notes</strong>:" + ...
                        "<ul><li>Imported content will be truncated if it exceeds the context window limit.</li>" + ...
                        "<li>PDF file is supported if Text Analytics Toolbox is available.</li></ul>";
                    addChatLog(app,role,content)
                    drawnow


                catch ME
                    % display error dialog
                    uialert(app.MatGPTUIFigure,ME.message,"Error");
                    app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                    return
                end
                %% Populate various fields
                % add new chat node
                if isempty(app.ChatTree.Children)
                    uitreenode(app.ChatTree,'Text','+ New Chat');
                end
                % append old chats to ChatTree
                if isfile(chatHistoryFile)
                    load(chatHistoryFile,"PreviousChats")

                    % Check if chat history is current
                    isLegacy = arrayfun(@(x) isfield(x,'model'), PreviousChats.chat_history);
                    if any(isLegacy)
                        uialert(app.MatGPTUIFigure, ...
                            "Legacy chat histry found" + newline + ...
                            "Save the legacy chats to text files.","Warning","Icon","warning");
                    end

                    % add old chats to ChatTree
                    for ii = 1:height(PreviousChats)
                        topic = PreviousChats.topic(ii);
                        s = PreviousChats.chat_history(ii);
                        uitreenode(app.ChatTree,"NodeData",s,"Text",topic, ... 
                            "ContextMenu",app.ContextMenu);
                    end
                end
                % Initialize Presets ListBox
                app.PresetsListBox.Items = app.Presets.name;
                app.PresetsListBox.ItemsData = 1:height(app.Presets);
                app.PresetsListBox.Value = 1;
                % set parameter defaults
                app.ModelDropDown.Value = app.Presets.model(1);
                app.MaxTokensEditField.Value = app.Presets.max_tokens(1);
                app.TemperatureEditField.Value = app.Presets.temperature(1);
                app.SystemPromptTextArea.Value = app.Presets.content(1);
                model = app.ModelDropDown.Value;
                contextWindow = app.Models(arrayfun(@(x) string(x.name), app.Models) == model).attributes.contextwindow;
                cutoff = app.Models(arrayfun(@(x) string(x.name), app.Models) == model).attributes.cutoff;
                str = "Context Window: " + contextWindow + " tokens, Cutoff: " + cutoff;
                app.ModelAttributesLabel.Text = str;

                apikey = getenv("OPENAI_API_KEY");
                if isempty(apikey)
                    % disable Start New Chat button
                    app.StartNewChatButton.Enable = "off";
                else
                    % enable Start New Chat button
                    app.StartNewChatButton.Enable = "on";
                    app.APIKeyNotFoundLampLabel.Text = "API Key Found";
                    app.APIKeyNotFoundLamp.Color = "Green";
                end
                % disable PaperClip image
                app.PaperClip.Enable = "off";
        end

        % Button pushed function: StartNewChatButton
        function StartNewChat(app, event)
            % set parameters
            model = string(app.ModelDropDown.Value);
            systemPrompt = join(string(app.SystemPromptTextArea.Value),newline);
            temperature = app.TemperatureEditField.Value;
            
            % get stop sequences
            stop = cell(1,4);
            stop{1} = app.Sequence1EditField.Value;
            stop{2} = app.Sequence2EditField.Value;
            stop{3} = app.Sequence3EditField.Value;
            stop{4} = app.Sequence4EditField.Value;
            stop(cellfun(@isempty,stop)) = [];

            % enable PaperClip image
            app.PaperClip.Enable = "on";

            % standard params
            params = {systemPrompt,'ModelName',model,'Temperature',temperature};
            % add stop sequence if exist
            if ~isempty(stop) 
                if model == "gpt-4-vision-preview"
                    uialert(app.MatGPTUIFigure, ...
                        "Stop sequences are not supported for gpt-4-vision-preview","Error");
                    return
                else
                    params = [params,{'StopSequences',string(stop)}];
                end
            end
            % get timeout
            timeout = app.TimeoutEditField.Value;
            if ~isempty(timeout)
                params = [params,{'TimeOut',timeout}];
            end
            % define streaming function
            if app.StreamResponseCheckBox.Value 
                if model == "gpt-4-vision-preview"
                    uialert(app.MatGPTUIFigure, ...
                            "Streaming is not supported for gpt-4-vision-preview","Error");
                    return
                else
                    % define streaming function handle
                    sfun = @(x)streamTokens(app,x);
                    app.StreamResponseCheckBox.Enable = "off";
                    % disable the token usage fields
                    app.CompletiontokensusedEditField.Enable ="off";
                    app.PrompttokensusedEditField.Enable = "off";
                    app.TotaltokensusedEditField.Enable = "off";
                end
            end

            try
                % instantiate the openAIChat class
                if exist('sfun','var')
                    app.Bot = openAIChat(params{:},StreamFun=sfun);
                else
                    app.Bot = openAIChat(params{:});
                end
                % instantiate the openAIMessages class with system prompt
                app.Messages = openAIMessages;
            catch ME
                % display error dialog
                uialert(app.MatGPTUIFigure,ME.message,"Error");
                % rethrow(ME)
            end
            
            % add new node to the tree
            nodeLabels = arrayfun(@(x) string(x.Text), app.ChatTree.Children);
            myChats = nodeLabels(contains(nodeLabels,"My Chat"));
            if isempty(myChats)
                n = 0;
            else
                myChats = erase(myChats,["💬 My Chat "," ...✏️"]);
                n = max(str2double(myChats));
            end
            % construct the node data
            s = struct('bot',app.Bot,'messages',app.Messages);
            uitreenode(app.ChatTree, "NodeData",s, ...
                "Text","💬 My Chat " + (n + 1) + " ...✏️", ...
                "ContextMenu",app.ContextMenu);
            % move the current chat to the second place
            app.ChatTree.Children = [app.ChatTree.Children(1);app.ChatTree.Children(end); ... 
                app.ChatTree.Children(2:end-1)];
            app.ChatTree.SelectedNodes = app.ChatTree.Children(2);
            % Intialize the HTML files
            resetChatHTML(app)
            % Initialize the chat log - this will just shows the "system" message for now
            addChatLog(app,"system",systemPrompt)
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            % update UI components
            app.SendButton.Enable = "on";
            n = app.PresetsListBox.Value;
            app.PromptTextArea.Value = app.Presets.prompt(n);

            % disable suggested questions and code testing for GPT-4 Turbo with Vision
            if model == "gpt-4-vision-preview"
                app.SuggestfollowupquestionsCheckBox.Value = 0;
                app.SuggestfollowupquestionsCheckBox.Enable = "off";
                app.StreamResponseCheckBox.Value = 0;
                app.StreamResponseCheckBox.Enable = "off";
                app.TestGeneratedMATLABCodeCheckBox.Value = 0;
                app.TestGeneratedMATLABCodeCheckBox.Enable = "off";
            else
                app.SuggestfollowupquestionsCheckBox.Value = 1;
                app.SuggestfollowupquestionsCheckBox.Enable = "on";
                app.StreamResponseCheckBox.Value = 1;
                app.StreamResponseCheckBox.Enable = "on";
            end
        end

        % Button pushed function: SendButton
        function send(app, event)
            if app.TestGeneratedMATLABCodeCheckBox.Value && ...
                    strcmp(app.Bot.ModelName,'gpt-4-vision-preview')
                uialert(app.MatGPTUIFigure, ...
                        "'Test Generated MATLAB Code' is not supported for gpt-4-vision-preview","Error");
                return
            end

            % get user prompt
            prompt = string(app.PromptTextArea.Value);
            if prompt == ""
                uialert(app.MatGPTUIFigure, ...
                        "Please provide a prompt.","Error");
                return
            else
                prompt = string(app.PromptTextArea.Value);
                prompt = join(prompt,newline);
            end

            % extract URL from the prompt
            content = getContentFromURL(app, prompt);
            if ~isstring(content)
                return
            elseif startsWith(content,["http://","https://"]) && endsWith(content,[".jpg",".png",".gif"])
                % image url
                app.Image = content;
            elseif strlength(content)
                % add content as function message
                app.Messages = addFunctionMessage(app,content);
                role = "function";
                addChatLog(app,role,content);
                drawnow
            end

            % check if user wants follow-up questions suggested
            if app.SuggestfollowupquestionsCheckBox.Value
                relatedQ = "After answering this question, suggest 3 related questions after 'Three related questions: '";
                prompt = prompt + newline + newline + relatedQ;
            end
            % get max tokens parameter
            max_tokens = app.MaxTokensEditField.Value;
            if strcmp(app.Bot.ModelName,'gpt-4-vision-preview')
                max_tokens = Inf;
            end

            % if streaming is not enabled
            if ~app.StreamResponseCheckBox.Value
                % display progress dialog box
                progressdlg = uiprogressdlg(app.MatGPTUIFigure,"Title","Please Wait", ...
                "Message","Waiting for API Response...", ...
                "Indeterminate","on");
            end

            % if user didn't selected an image
            if isempty(app.Image)
                % add the user prompt to Messages
                app.Messages = addUserMessage(app.Messages,prompt);
                % extract messages struct and add to HTML
                s = app.Messages.Messages;
                addChatLog(app,s{end}.role,s{end}.content);
            % if user selected an image
            else
                % add the user prompt to Messages
                app.Messages = addUserMessageWithImages(app.Messages, prompt, app.Image);
                % extract messages struct and add to HTML
                s = app.Messages.Messages;
                addChatLog(app,s{end}.role,s{end}.content{1}.text);
            end
            drawnow
            pause(1/20)
            
            % send the prompt to API
            try
                % remove test reports before sending user prompt
                messages = removeTestReports(app);
                % send the query
                [response,m,r] = generate(app.Bot,messages,MaxNumTokens=max_tokens);

            catch ME
                uialert(app.MatGPTUIFigure,ME.message,"Error");
                if contains(ME.message, "Check your API key.")
                    app.APIKeyNotFoundLampLabel.Text = 'API Key Not Valid';
                    app.APIKeyNotFoundLamp.Color = 'Red';
                    app.StartNewChatButton.Enable = "off";
                    app.ChatTree.SelectedNodes.delete;
                    app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                end
                return
                % rethrow(ME)
            end
            
            % check if you have the response
            if ~isempty(fieldnames(m))
                % add the response to Messages
                app.Messages = addResponseMessage(app.Messages,m);
            else
                err = r.Body.Data.error;
                if ~isempty(err.code)
                    uialert(app.MatGPTUIFigure,err.message,string(err.type) + ...
                        ": " + err.code);
                else
                    uialert(app.MatGPTUIFigure,err.message,string(err.type) + ...
                        ": []");
                end
                return
            end

            % add messages to the current chat
            selectedChat = app.ChatTree.SelectedNodes;
            selectedChat.NodeData.messages = app.Messages;
            
            % check if related answer is contained in the response
            if contains(response,"Three related questions:")
                % extract answer
                answer = extractBefore(response,"Three related questions:");
                % extract the related questions
                relquestions = extractAfter(response,"Three related questions:");
                relquestions = split(relquestions,newline);
                relquestions = relquestions(strlength(relquestions)>0 & relquestions ~= " ");
                relquestions = erase(relquestions, digitsPattern(1)+". ");
                htmlwrapper = "<button class=""relquestion"">";
                htmlwrapper = htmlwrapper + relquestions + "</button>";
                answer = answer + join(htmlwrapper);
                s = app.Messages.Messages;
                addChatLog(app,s{end}.role,answer);
            else
                s = app.Messages.Messages;
                addChatLog(app,s{end}.role,s{end}.content);
            end
            drawnow

            % clear the prompt
            app.PromptTextArea.Value = "";
            % clear the image
            app.Image = [];

            % if streaming is not enabled
            if ~app.StreamResponseCheckBox.Value
                % display the tokens used and cost
                usage = r.Body.Data.usage;
                app.CompletiontokensusedEditField.Value = app.CompletiontokensusedEditField.Value ...
                    + usage.completion_tokens;
                app.PrompttokensusedEditField.Value = app.PrompttokensusedEditField.Value ...
                    + usage.prompt_tokens;
                app.TotaltokensusedEditField.Value = app.TotaltokensusedEditField.Value + ...
                    usage.total_tokens;
            end

            % If Validated Generated MATLAB code checkbox is checked 
            app.ErrorMessages = {};
            if app.TestGeneratedMATLABCodeCheckBox.Value

                % if streaming is enabled
                if app.StreamResponseCheckBox.Value
                    % display progress dialog box
                    progressdlg = uiprogressdlg(app.MatGPTUIFigure,"Title","Please Wait", ...
                    "Message","Testing code...", ...
                    "Indeterminate","on");
                else
                    % Upate the progress dialog
                    progressdlg.Message = progressdlg.Message + " Done" + newline + "Testing code... ";
                end
                
                % set up code checker
                checker = CodeChecker(response);
                % run checks
                runChecks(checker); 
                app.ErrorMessages = checker.ErrorMessages;
                % add test report to chat log
                addChatLog(app,"MATLAB",checker.Report);
                drawnow
                % add test report to Messages as function message
                app.Messages = addFunctionMessage(app,checker.Report);
                % update ChatTree data
                selectedChat.NodeData.messages = app.Messages;
            end
            
            % if progress dialog is open
            if exist('progressdlg','var')
                % close the progress bar
                progressdlg.Message = [progressdlg.Message ' Done'];
                progressdlg.Title = 'Done';
                close(progressdlg);
                clear progressdlg
            end
        end

        % Value changed function: PresetsListBox
        function setPreset(app, event)
            n = app.PresetsListBox.Value;
            app.ModelDropDown.Value = app.Presets.model(n);
            if strcmp(app.ModelDropDown.Value,"gpt-4-vision-preview")
                app.MaximumnumberoftokenstoincludeinresponseLabel.Text = "Max Tokens is not supported for gpt-4-vision-preview";
                app.MaxTokensEditField.Value = Inf;
            else
                app.MaximumnumberoftokenstoincludeinresponseLabel.Text = "Maximum number of tokens to include in response";
            end
            app.MaxTokensEditField.Value = app.Presets.max_tokens(n);
            app.TemperatureEditField.Value = app.Presets.temperature(n);
            app.SystemPromptTextArea.Value = app.Presets.content(n);
            app.PromptTextArea.Value = app.Presets.prompt(n);
            app.TestGeneratedMATLABCodeCheckBox.Value = app.Presets.test_code(n);
        end

        % Selection changed function: ChatTree
        function ChatTreeSelectionChanged(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            % if New Chat
            if selectedNodes == app.ChatTree.Children(1)
                app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                resetChatHTML(app);
                app.StreamResponseCheckBox.Enable = "on";
                app.StreamResponseCheckBox.Value = 1;
                drawnow
            % if previous chat
            else
                % Reset the HTML table            
                resetChatHTML(app)

                % the check if the hisotry is current
                if isfield(selectedNodes.NodeData,'model')
                    uialert(app.MatGPTUIFigure,"Legacy chat history." + ...
                        newline + "Double click and save chat to file.","Warning",Icon="warning");
                    return
                end
                
                % extact node data
                app.Bot = selectedNodes.NodeData.bot;
                app.Messages = selectedNodes.NodeData.messages;
                
                % extract past messages and add to HTML
                role = app.Bot.SystemPrompt{1}.role;
                content = app.Bot.SystemPrompt{1}.content;
                addChatLog(app,role,content)
                drawnow
                pause(1/20)
                history = app.Messages.Messages;
                for ii = 1:numel(history)
                    role = history{ii}.role;
                    content = history{ii}.content;
                    addChatLog(app,role,content)
                    drawnow
                    pause(1/20)
                end
                app.SendButton.Enable = "on";
                app.PaperClip.Enable = "on";
            end
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            selectedChat = app.ChatTree.SelectedNodes;
            prompt = 'Enter new name';
            dlgtitle = 'Rename Chat';
            definput = selectedChat.Text;
            definput = cellstr(erase(definput,["💬 ","...✏️"]));
            answer = string(inputdlg(prompt,dlgtitle,[1 40],definput));
            if answer ~= ""
                selectedChat.Text = "💬 " + answer + "...✏️";
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            if isempty(selectedNodes)
                prompt = 'Please select a chat to delete.';
                dlgtitle = 'No chat selected';
                answer = questdlg(prompt,dlgtitle,'Cancel','Cancel');
            else
                prompt = 'Are you sure you want to delete the chat?';
                dlgtitle = 'Confirm your choice';
                answer = questdlg(prompt,dlgtitle,'Delete','Cancel','Cancel');
                switch answer
                    case 'Delete'
                        
                        selectedNodes.delete;
                        resetChatHTML(app);
                        drawnow
                        app.PromptTextArea.Value = "";
                        app.SendButton.Enable = "off";
                end
            end
        end

        % Menu selected function: SavetofileMenu
        function SaveChatMenuSelected(app, event)
            filename = uiputfile('chat history.txt');
            if ~isequal(filename,0)
                selectedNodes = app.ChatTree.SelectedNodes;
                messages = selectedNodes.NodeData.messages;
                if isfield(selectedNodes.NodeData,"model")
                    roles = arrayfun(@(x) string(x.role) , messages)';
                    contents = arrayfun(@(x) string(x.content), messages)';
                else
                    roles = cellfun(@(x) string(x.role), messages.Messages)';
                    contents = cellfun(@(x) string(x.content), messages.Messages)';
                end
                str = pad(roles,max(strlength(roles))) + ": " + contents;
                writelines(str,filename)
            end
        end

        % Close request function: MatGPTUIFigure
        function MatGPTUIFigureCloseRequest(app, event)
            chats = app.ChatTree.Children(2:end);
            topics = arrayfun(@(x) string(x.Text), chats);
            % check if chat tree includes legacy chats
            isLegacy = arrayfun(@(x) isfield(x.NodeData,'model'), chats);
            msg = "Legacy chat history found: ";
            legacyTopics = topics(isLegacy);
            for ii = 1:numel(legacyTopics)
                msg = msg + newline + legacyTopics(ii);
            end
            msg = msg + newline + newline + "1) Double click and save chats to file" + ...
                newline + "2) Delete chats from the chat history.";

            if ~isempty(isLegacy) && all(isLegacy)                
                YN = uiconfirm(app.MatGPTUIFigure,"You have legacy chat history." + ...
                    newline + newline + 'Do you want to close the app?', 'Close request');
                if strcmp(YN,'OK')
                    delete(app)
                else
                    uialert(app.MatGPTUIFigure,msg,"Warning","Icon","warning");
                end
            else
                if any(isLegacy)
                    answer = questdlg("Closing App overwrites legacy chats with new chats. Proceed?","Closing App","Yes","Cancel","Cancel");
                    switch answer
                        case "Cancel"
                                uialert(app.MatGPTUIFigure,msg,"Warning", Icon="warning");
                            return
                    end
                end
                newChats = chats(~isLegacy);
                chat_history = arrayfun(@(x) x.NodeData, newChats);
                PreviousChats = table;
                PreviousChats.topic = topics(~isLegacy);
                PreviousChats.chat_history = chat_history;
                % make sure the message object contains at least 3 messages
                isValid = arrayfun(@(x) numel(x.messages.Messages), PreviousChats.chat_history) >= 2;
                PreviousChats(~isValid,:) = [];          
                % if there is any messages left
                chatHistoryFile = fullfile(app.Path,"contents","ChatData.mat");
                if numel(PreviousChats.topic) > 0
                    save(chatHistoryFile,"PreviousChats")
                else
                    if isfile(chatHistoryFile)
                        delete(chatHistoryFile);
                    end
                end
                delete(app)
            end
        end

        % Button pushed function: SaveKeyButton
        function SaveKeyButtonPushed(app, event)
            apikey = app.YourOpenAIAPIKeyEditField.Value;
            if ~isempty(apikey)
                setenv("OPENAI_API_KEY",apikey)
                app.YourOpenAIAPIKeyEditField.Value = "";
                app.StartNewChatButton.Enable = "on";
                app.APIKeyNotFoundLampLabel.Text = "API Key Found";
                app.APIKeyNotFoundLamp.Color = "Green";
            end
        end

        % Double-clicked callback: ChatTree
        function ChatTreeDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            location = event.InteractionInformation.Location;
            cm = node.ContextMenu;
            open(cm,location)
        end

        % Image clicked function: PaperClip
        function PaperClipClicked(app, event)

            if strcmp(app.Bot.ModelName,'gpt-4-vision-preview')
                fileTypes = ["*.jpg;*.png;*.gif", ...
                    "Image files (*.jpg,*.png,*.gif)"];
            else
                fileTypes = ["*.m;*.mlx", ... 
                    "Source files (*.m,*.mlx)"];
                fileTypes = [fileTypes; ["*.csv;*.txt", ...
                    "Data files (*.csv,*.txt)"]];
            end
            if license('test','Text_Analytics_Toolbox')
                fileTypes = [fileTypes; ["*.pdf", ...
                    "PDF files (*.pdf)"]];
            end
            % open the file selection dialog
            [filename,path,~] = uigetfile( ...
                fileTypes,'Select a file');
            filepath = string(fullfile(path,filename));
            % if user select a file
            if filename ~= 0
                if endsWith(filename,[".jpg",".png",".gif"])
                    im = imread(filepath);
                    [height,width,~] = size(im);
                    if max([height,width]) < 2000 && min([height,width]) < 768
                        % add image
                        app.Image = filepath;
                        % update the prompt
                        app.PromptTextArea.Value = "What is in this image?" +  " 📎" + filename;
                    else
                    uialert(app.MatGPTUIFigure,"Image size exceeds the limit (max 2000x768 or 768x2000)","Error");
                    end
                else
                    % check the file size
                    s = dir(filepath);
                    % return error if it exceeds the limit
                    if s.bytes > 5.12e+8
                        uialert(app.MatGPTUIFigure,"File size exceeds the limit (< 512MB)","Error");
                        return
                    end
                    % if live script was chosen
                    if endsWith(filename,".mlx")
                        currentpath = pwd;
                        outpath = fullfile(string(currentpath),filename);
                        outpath = replace(outpath,".mlx",".m");
                        export(filepath,outpath)
                        fileContent = readlines(outpath);
                    % if PDF was chosen
                    elseif endsWith(filename,".pdf")
                        fileContent = extractFileText(filepath);
                    % if m-file, csv or txt file
                    else
                        fileContent = readlines(filepath);
                    end
                    % create a text scalar by joining rows 
                    fileContent = fileContent + newline;
                    fileContent = join(fileContent);
    
                    % make sure it fits the context window
                    fittedContent = fitContextWindow(app,fileContent);
    
                    % add appropriate HTML tags based on the file type
                    if endsWith(filename,[".m",".mlx"])
                        fittedContent = "<code>" + fittedContent + "</code>";
                    else
                        fittedContent = "<pre>" + fittedContent + "</pre>";
                    end
        
                    % add the file content to Messages
                    prompt = "File " + filename + " content: " + newline + newline;
                    prompt = prompt + fittedContent;
                    app.Messages = addFunctionMessage(app,prompt);
    
                    % update chat HTML
                    role = 'function';
                    content = prompt;
                    addChatLog(app,role,content);
                    drawnow
                    % update the prompt
                    app.PromptTextArea.Value = "What does this file contain?" +  " 📎" + filename;
                end
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            model = app.ModelDropDown.Value;
            contextWindow = app.Models(arrayfun(@(x) string(x.name), app.Models) == model).attributes.contextwindow;
            cutoff = app.Models(arrayfun(@(x) string(x.name), app.Models) == model).attributes.cutoff;
            str = "Context Window: " + contextWindow + " tokens, Cutoff: " + cutoff;
            app.ModelAttributesLabel.Text = str;
            if strcmp(model,'gpt-4-vision-preview')
                app.MaximumnumberoftokenstoincludeinresponseLabel.Text = "Max Tokens is not supported for gpt-4-vision-preview";
                app.MaxTokensEditField.Value = Inf;
            else
                app.MaximumnumberoftokenstoincludeinresponseLabel.Text = "Maximum number of tokens to include in response";
            end
        end

        % Data changed function: ChatHTML
        function ChatHTMLDataChanged(app, event)
            data = app.ChatHTML.Data;
            app.PromptTextArea.Value = data;
        end

        % Value changed function: StreamResponseCheckBox
        function StreamResponseCheckBoxValueChanged(app, event)
            if app.StreamResponseCheckBox.Value
                app.UsagedataisnotavailableinstreamingmodeLabel_2.Visible = "on";
            else
                app.UsagedataisnotavailableinstreamingmodeLabel_2.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MatGPTUIFigure = uifigure('Visible', 'off');
            app.MatGPTUIFigure.Name = 'MatGPT';
            app.MatGPTUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatGPTUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MatGPTUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChatGridLayout = uigridlayout(app.MainTab);
            app.MatGPTpoweredbyOpenAIChatGPTAPILabel = uilabel(app.ChatGridLayout);
            app.MatGPTpoweredbyOpenAIChatGPTAPILabel.Text = 'MatGPT powered by OpenAI™ ChatGPT API';
            app.SendButton = uibutton(app.ChatGridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @send, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.Logo = uiimage(app.ChatGridLayout);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'images', 'MatGPTlogo.png');
            app.ChatTree = uitree(app.ChatGridLayout);
            app.ChatTree.SelectionChangedFcn = createCallbackFcn(app, @ChatTreeSelectionChanged, true);
            app.ChatTree.DoubleClickedFcn = createCallbackFcn(app, @ChatTreeDoubleClicked, true);
            app.PromptTextArea = uitextarea(app.ChatGridLayout);
            app.TabGroup2 = uitabgroup(app.ChatGridLayout);
            app.ChatTab = uitab(app.TabGroup2);
            app.ChatTab.Title = 'Chat';
            app.ChatHTML = uihtml(app.ChatTab);
            app.ChatHTML.DataChangedFcn = createCallbackFcn(app, @ChatHTMLDataChanged, true);
            app.UsageTab = uitab(app.TabGroup2);
            app.UsageTab.Title = 'Usage';
            app.GridLayout = uigridlayout(app.UsageTab);
            app.CompletiontokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.CompletiontokensusedEditFieldLabel.Text = 'Completion tokens used';
            app.CompletiontokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.CompletiontokensusedEditField.Editable = 'off';
            app.PrompttokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.PrompttokensusedEditFieldLabel.Text = 'Prompt tokens used';
            app.PrompttokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.PrompttokensusedEditField.Editable = 'off';
            app.TotaltokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.TotaltokensusedEditFieldLabel.Text = 'Total tokens used';
            app.TotaltokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.TotaltokensusedEditField.Editable = 'off';
            app.Hyperlink2 = uihyperlink(app.GridLayout);
            app.Hyperlink2.URL = 'https://openai.com/pricing';
            app.Hyperlink2.Text = 'OPENAI pricing page';
            app.TocalculateAPIfeespleaseconsultLabel = uilabel(app.GridLayout);
            app.TocalculateAPIfeespleaseconsultLabel.Text = 'To calculate API fees, please consult';
            app.UsagedataisnotavailableinstreamingmodeLabel = uilabel(app.GridLayout);
            app.UsagedataisnotavailableinstreamingmodeLabel.Text = 'Usage data is not available in streaming mode. ';
            app.TestGeneratedMATLABCodeCheckBox = uicheckbox(app.ChatGridLayout);
            app.TestGeneratedMATLABCodeCheckBox.Text = 'Test Generated MATLAB Code';
            app.SuggestfollowupquestionsCheckBox = uicheckbox(app.ChatGridLayout);
            app.SuggestfollowupquestionsCheckBox.Text = 'Suggest follow-up questions';
            app.SuggestfollowupquestionsCheckBox.Value = true;
            app.PaperClip = uiimage(app.ChatGridLayout);
            app.PaperClip.ImageClickedFcn = createCallbackFcn(app, @PaperClipClicked, true);
            app.PaperClip.Enable = 'off';
            app.PaperClip.ImageSource = fullfile(pathToMLAPP, 'images', 'paperclip.png');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsGridLayout = uigridlayout(app.SettingsTab);
            app.Hyperlink = uihyperlink(app.SettingsGridLayout);
            app.Hyperlink.URL = 'https://platform.openai.com/account/api-keys';
            app.Hyperlink.Text = 'https://platform.openai.com/account/api-keys';
            app.MaximumnumberoftokenstoincludeinresponseLabel = uilabel(app.SettingsGridLayout);
            app.MaximumnumberoftokenstoincludeinresponseLabel.Text = 'Maximum number of tokens to include in response';
            app.moreprecise1balanced2morecreativeLabel = uilabel(app.SettingsGridLayout);
            app.moreprecise1balanced2morecreativeLabel.Text = '0 = more precise, 1 = balanced, 2 = more creative ';
            app.Label = uilabel(app.SettingsGridLayout);
            app.Label.Text = 'Describe the role you assign to the chatbot. The default is an AI assistant.';
            app.StartNewChatButton = uibutton(app.SettingsGridLayout, 'push');
            app.StartNewChatButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewChat, true);
            app.StartNewChatButton.Enable = 'off';
            app.StartNewChatButton.Text = 'Start New Chat';
            app.ChooseapresetLabel = uilabel(app.SettingsGridLayout);
            app.ChooseapresetLabel.Text = 'Choose a preset';
            app.GetyourAPIkeyfromLabel = uilabel(app.SettingsGridLayout);
            app.GetyourAPIkeyfromLabel.Text = 'Get your API key from ';
            app.OrcustomizeparametersyourselfLabel = uilabel(app.SettingsGridLayout);
            app.OrcustomizeparametersyourselfLabel.Text = 'Or customize parameters yourself';
            app.SetOpenAIAPIKeyLabel = uilabel(app.SettingsGridLayout);
            app.SetOpenAIAPIKeyLabel.Text = 'Set OpenAI API Key';
            app.ModelAttributesLabel = uilabel(app.SettingsGridLayout);
            app.ModelAttributesLabel.Text = 'Model Attributes';
            app.ModelDropDownLabel = uilabel(app.SettingsGridLayout);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SettingsGridLayout);
            app.ModelDropDown.Items = {'gpt-3.5-turbo', 'gpt-3.5-turbo-1106', 'gpt-3.5-turbo-16k', 'gpt-4', 'gpt-4-0613', 'gpt-4-1106-preview', 'gpt-4-vision-preview', '''gpt-4-turbo-preview'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'gpt-3.5-turbo';
            app.MaxTokensEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.MaxTokensEditFieldLabel.Text = 'Max Tokens';
            app.MaxTokensEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.MaxTokensEditField.Limits = [1 Inf];
            app.MaxTokensEditField.Value = 1000;
            app.TemperatureEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.TemperatureEditField.Limits = [0 Inf];
            app.TemperatureEditField.Value = 1;
            app.SystemPromptTextAreaLabel = uilabel(app.SettingsGridLayout);
            app.SystemPromptTextAreaLabel.Text = 'System Prompt';
            app.SystemPromptTextArea = uitextarea(app.SettingsGridLayout);
            app.PresetsListBoxLabel = uilabel(app.SettingsGridLayout);
            app.PresetsListBoxLabel.Text = 'Presets';
            app.PresetsListBox = uilistbox(app.SettingsGridLayout);
            app.PresetsListBox.Items = {};
            app.PresetsListBox.ValueChangedFcn = createCallbackFcn(app, @setPreset, true);
            app.PresetsListBox.Value = {};
            app.APIKeyNotFoundLampLabel = uilabel(app.SettingsGridLayout);
            app.APIKeyNotFoundLampLabel.Text = 'API Key Not Found';
            app.APIKeyNotFoundLamp = uilamp(app.SettingsGridLayout);
            app.YourOpenAIAPIKeyEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.YourOpenAIAPIKeyEditFieldLabel.Text = 'Your OpenAI API Key';
            app.YourOpenAIAPIKeyEditField = uieditfield(app.SettingsGridLayout, 'text');
            app.SaveKeyButton = uibutton(app.SettingsGridLayout, 'push');
            app.SaveKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKeyButtonPushed, true);
            app.SaveKeyButton.Text = 'Save Key';
            app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel = uilabel(app.SettingsGridLayout);
            app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel.Text = 'Key will be saved in environment variable "OPENAI_API_Key"';
            app.TimeoutEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.TimeoutEditField.Value = 10;
            app.secsLabel = uilabel(app.SettingsGridLayout);
            app.secsLabel.Text = 'secs';
            app.StreamResponseCheckBox = uicheckbox(app.SettingsGridLayout);
            app.StreamResponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @StreamResponseCheckBoxValueChanged, true);
            app.StreamResponseCheckBox.Text = 'Stream Response';
            app.StreamResponseCheckBox.Value = true;
            app.UsagedataisnotavailableinstreamingmodeLabel_2 = uilabel(app.SettingsGridLayout);
            app.UsagedataisnotavailableinstreamingmodeLabel_2.Text = 'Usage data is not available in streaming mode. ';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.GridLayout2 = uigridlayout(app.AdvancedTab);
            app.StopSequencesLabel = uilabel(app.GridLayout2);
            app.StopSequencesLabel.Text = 'Stop Sequences';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = ' Sequence 1';
            app.Sequence1EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = ' Sequence 2';
            app.Sequence2EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = ' Sequence 3';
            app.Sequence3EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = 'Sequence 4';
            app.Sequence4EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = 'Specify the sequences where the API will stop generating further tokens';
            app.ContextMenu = uicontextmenu(app.MatGPTUIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.SavetofileMenu = uimenu(app.ContextMenu);
            app.SavetofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveChatMenuSelected, true);
            app.SavetofileMenu.Text = 'Save to file';
            % Show the figure after all components are created
            app.MatGPTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatGPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatGPTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatGPTUIFigure)
        end
    end
end